classdef App_Noi_Suy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        BNGTHITLPPanel           matlab.ui.container.Panel
        out_put                  matlab.ui.control.TextArea
        aThcNiSuyLabel           matlab.ui.control.Label
        Gia_tri_x                matlab.ui.control.EditField
        NhpdliuxLabel            matlab.ui.control.Label
        List                     matlab.ui.control.ListBox
        ChnPhngPhpNiSuyLabel     matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        SliderLabel              matlab.ui.control.Label
        Gia_tri_y                matlab.ui.control.EditField
        NhpDLiuyLabel            matlab.ui.control.Label
        Gia_tri_noisuy           matlab.ui.control.NumericEditField
        GiTrCnNiSuyLabel         matlab.ui.control.Label
        NTLNHIUKHINPanel         matlab.ui.container.Panel
        RESETButton              matlab.ui.control.Button
        RUNButton                matlab.ui.control.Button
        BNGKTQUPanel             matlab.ui.container.Panel
        ket_qua_noisuy           matlab.ui.control.NumericEditField
        KtQuNiSuyEditFieldLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: List, RUNButton, ket_qua_noisuy
        function RUNButtonPushed(app, event)
x = str2num(app.Gia_tri_x.Value);
y = str2num(app.Gia_tri_y.Value);
list = app.List.Value;
t = app.Gia_tri_noisuy.Value;

if list == '0'
    result = noisuy_newton(x, y);
    p = polyval(result, t);
    chuoi = 'Kết quả: ';
    for i = length(result):-1:1
        if result(i) ~= 0
            % Hiển thị hệ số và biến x
            if i == length(result)
                chuoi = [chuoi, sprintf('%+g', result(i))];
            else
                if length(result)-i == 1
                    chuoi = [chuoi, sprintf('%+gx', result(i))];
                else
                    chuoi = [chuoi, sprintf('%+gx^%d ', result(i), length(result)-i)];
                end
            end
        end
    end

    resultString = sprintf('%s= %0.2f', chuoi, p);
    app.out_put.Value = resultString;
    app.ket_qua_noisuy.Value = p;

else
    a = noisuy_lagrange(x, y);
    p = polyval(a, t);
    chuoi = 'Kết quả: ';
    for i = length(a):-1:1
        if a(i) ~= 0
            % Hiển thị hệ số và biến x
            if i == length(a)
                chuoi = [chuoi, sprintf('%+g', a(i))];
            else
                if length(a)-i == 1
                    chuoi = [chuoi, sprintf('%+gx', a(i))];
                else
                    chuoi = [chuoi, sprintf('%+gx^%d ', a(i), length(a)-i)];
                end
            end
        end
    end

    resultString = sprintf('%s= %0.2f', chuoi, p);
    app.out_put.Value = resultString;
    app.ket_qua_noisuy.Value = p;
    app.ket_qua_noisuy.Value = p;
end

%--------------------------------------------------------------------------------

if list == '0'
    [x_values, y_values, t, p] = app_dothi_newton(x, y, t);
    plot(app.UIAxes, x_values, y_values, 'r-', LineWidth = 2);
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, x, y, 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b');
    plot(app.UIAxes, t, p, 'go', 'MarkerSize', 8, 'MarkerFaceColor', 'g');

    title(app.UIAxes, 'ĐỒ THỊ NỘI SUY NEWTON');
    xlabel(app.UIAxes, 'x');
    ylabel(app.UIAxes, 'f(x)');
    legend(app.UIAxes, 'f(x) Newton', 'Data Points', ['Điểm nội suy x = ' num2str(t)]);
    grid(app.UIAxes, 'on');
    hold(app.UIAxes, 'off');
    
elseif list == '1'
    [a_values, b_values, t1, p1] = app_dothi_lagrange(x, y, t);
    plot(app.UIAxes, a_values, b_values, 'y-', LineWidth = 2);
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, x, y, 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'k');
    plot(app.UIAxes, t1, p1, 'mo', 'MarkerSize', 8, 'MarkerFaceColor', 'm');

    title(app.UIAxes, 'ĐỒ THỊ NỘI SUY LAGRANGE');
    xlabel(app.UIAxes, 'x');
    ylabel(app.UIAxes, 'f(x)');
    legend(app.UIAxes, 'f(x) Lagrange', 'Điểm dữ liệu', ['Điểm nội suy x = ' num2str(t)]);
    grid(app.UIAxes, 'on');
    hold(app.UIAxes, 'off');
end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Callback function: Slider, Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            changingValue = event.Value;
            newX = str2num(app.Gia_tri_x.Value); 
            app.Slider.Limits = [min(newX), max(newX)];
           
            value_slider = app.Slider.Value;
            app.Gia_tri_noisuy.Value = value_slider;
            value_slider = app.Gia_tri_noisuy.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BNGKTQUPanel = uipanel(app.UIFigure);
            app.BNGKTQUPanel.Title = 'BẢNG KẾT QUẢ';
            app.UIAxes = uiaxes(app.BNGKTQUPanel);
            title(app.UIAxes, 'ĐỒ THỊ NỘI SUY')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.KtQuNiSuyEditFieldLabel = uilabel(app.BNGKTQUPanel);
            app.KtQuNiSuyEditFieldLabel.Text = 'Kết Quả Nội Suy';
            app.ket_qua_noisuy = uieditfield(app.BNGKTQUPanel, 'numeric');
            app.ket_qua_noisuy.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.NTLNHIUKHINPanel = uipanel(app.UIFigure);
            app.NTLNHIUKHINPanel.Title = 'NÚT LỆNH ĐIỀU KHIỂN';
            app.RUNButton = uibutton(app.NTLNHIUKHINPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.RESETButton = uibutton(app.NTLNHIUKHINPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.BNGTHITLPPanel = uipanel(app.UIFigure);
            app.BNGTHITLPPanel.Title = 'BẢNG THIẾT LẬP';
            app.GiTrCnNiSuyLabel = uilabel(app.BNGTHITLPPanel);
            app.GiTrCnNiSuyLabel.Text = {'Giá Trị '; 'Cần Nội Suy'};
            app.Gia_tri_noisuy = uieditfield(app.BNGTHITLPPanel, 'numeric');
            app.NhpDLiuyLabel = uilabel(app.BNGTHITLPPanel);
            app.NhpDLiuyLabel.Text = {'Nhập '; 'Dữ Liệu y'};
            app.Gia_tri_y = uieditfield(app.BNGTHITLPPanel, 'text');
            app.SliderLabel = uilabel(app.BNGTHITLPPanel);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.BNGTHITLPPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.ChnPhngPhpNiSuyLabel = uilabel(app.BNGTHITLPPanel);
            app.ChnPhngPhpNiSuyLabel.Text = {'Chọn'; 'Phương Pháp'; 'Nội Suy'};
            app.List = uilistbox(app.BNGTHITLPPanel);
            app.List.Items = {'Nội Suy Newton', 'Nội Suy Lagrange'};
            app.List.ItemsData = {'0', '1'};
            app.List.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.List.Value = '0';
            app.NhpdliuxLabel = uilabel(app.BNGTHITLPPanel);
            app.NhpdliuxLabel.Text = {'Nhập'; 'Dữ Liệu x'};
            app.Gia_tri_x = uieditfield(app.BNGTHITLPPanel, 'text');
            app.aThcNiSuyLabel = uilabel(app.BNGTHITLPPanel);
            app.aThcNiSuyLabel.Text = {'Đa Thức'; 'Nội Suy'};
            app.out_put = uitextarea(app.BNGTHITLPPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Noi_Suy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end