classdef Daoham < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_Daoham                      matlab.ui.container.Tab
        DropDown_Way                    matlab.ui.control.DropDown
        ChoncachnhpDropDownLabel        matlab.ui.control.Label
        NhapmauLabel                    matlab.ui.control.Label
        Button_Delete                   matlab.ui.control.Button
        PhantinhLabel                   matlab.ui.control.Label
        EditField_xa                    matlab.ui.control.EditField
        NhpgiatrixLabel                 matlab.ui.control.Label
        EditField_ya                    matlab.ui.control.EditField
        NhpgiatriyLabel                 matlab.ui.control.Label
        DropDown_Saiso                  matlab.ui.control.DropDown
        ChongiatrisaisDropDownLabel     matlab.ui.control.Label
        HoacLabel                       matlab.ui.control.Label
        EditField_y                     matlab.ui.control.NumericEditField
        KtquaEditFieldLabel             matlab.ui.control.Label
        EditField_x                     matlab.ui.control.NumericEditField
        NhpgiatricntinhaohamEditFieldLabel  matlab.ui.control.Label
        DropDown_Choose                 matlab.ui.control.DropDown
        ChonphngphapaohamDropDownLabel  matlab.ui.control.Label
        EditField_h                     matlab.ui.control.NumericEditField
        NhpbcnhayhEditFieldLabel        matlab.ui.control.Label
        Button_Calculate                matlab.ui.control.Button
        EditField_fx                    matlab.ui.control.EditField
        HamsfxLabel                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button_Calculate, DropDown_Saiso
        function Button_CalculatePushed(app, event)
            Way = app.DropDown_Way.Value;
            x = app.EditField_x.Value;
            Choose = app.DropDown_Choose.Value;
            Saiso = app.DropDown_Saiso.Value;
            if Way == "Nhập mẫu"
                xa = str2num(app.EditField_xa.Value);
                ya = str2num(app.EditField_ya.Value);
                y = Taylorapp(xa, ya, x, Choose, Saiso);
            else
                fx = app.EditField_fx.Value;
                h = app.EditField_h.Value;
                y = Tinhdaohamfx(fx, h, x, Choose, Saiso);
            end
            app.EditField_y.Value = y;
        end

        % Value changed function: DropDown_Choose, EditField_x
        function EditField_xValueChanged(app, event)
            x = app.EditField_x.Value;
            Way = app.DropDown_Way.Value;
            if Way == "Nhập mẫu"
                xa = str2num(app.EditField_xa.Value);
                if x == xa(1)
                    app.DropDown_Choose.Items = {'Xấp xỉ tiến'};
                elseif x == xa(end)
                    app.DropDown_Choose.Items = {'Xấp xỉ lùi'};
                else
                    app.DropDown_Choose.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
                end
                Choose = app.DropDown_Choose.Value;
                if Choose == "Xấp xỉ trung tâm"
                    if x == xa(2) || x == xa(length(xa) - 1)
                        app.DropDown_Saiso.Items = {'O(h^2)'};
                    else
                        app.DropDown_Saiso.Items = {'O(h^2)', 'O(h^4)'};
                    end
                elseif Choose == "Xấp xỉ tiến"
                    if x == xa(length(xa) - 1)
                        app.DropDown_Saiso.Items = {'O(h)'};
                    else
                        app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
                    end
                else
                    if x == xa(2)
                        app.DropDown_Saiso.Items = {'O(h)'};
                    else
                        app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
                    end
                end
            else
                Choose = app.DropDown_Choose.Value;
                if Choose == "Xấp xỉ trung tâm"
                    app.DropDown_Saiso.Items = {'O(h^2)', 'O(h^4)'};
                else
                    app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
                end
            end
        end

        % Button pushed function: Button_Delete
        function Button_DeletePushed(app, event)
            app.EditField_xa.Value = '';
            app.EditField_ya.Value = '';
            app.EditField_fx.Value = '';
            app.EditField_h.Value = 0;
            app.DropDown_Choose.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DropDown_Choose.Value = 'Xấp xỉ tiến';
            app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
            app.DropDown_Saiso.Value = 'O(h)';
            app.EditField_x.Value = 0;
            app.EditField_y.Value = 0;
            app.DropDown_Way.Value = 'Nhập mẫu';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Daoham = uitab(app.TabGroup);
            app.Tab_Daoham.Title = 'Đạo hàm';
            app.HamsfxLabel = uilabel(app.Tab_Daoham);
            app.HamsfxLabel.Text = 'Nhập hàm số f(x)';
            app.EditField_fx = uieditfield(app.Tab_Daoham, 'text');
            app.Button_Calculate = uibutton(app.Tab_Daoham, 'push');
            app.Button_Calculate.ButtonPushedFcn = createCallbackFcn(app, @Button_CalculatePushed, true);
            app.Button_Calculate.Text = 'Tính đạo hàm';
            app.NhpbcnhayhEditFieldLabel = uilabel(app.Tab_Daoham);
            app.NhpbcnhayhEditFieldLabel.Text = 'Nhập bước nhảy (h)';
            app.EditField_h = uieditfield(app.Tab_Daoham, 'numeric');
            app.ChonphngphapaohamDropDownLabel = uilabel(app.Tab_Daoham);
            app.ChonphngphapaohamDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.DropDown_Choose = uidropdown(app.Tab_Daoham);
            app.DropDown_Choose.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DropDown_Choose.ValueChangedFcn = createCallbackFcn(app, @EditField_xValueChanged, true);
            app.DropDown_Choose.Value = 'Xấp xỉ tiến';
            app.NhpgiatricntinhaohamEditFieldLabel = uilabel(app.Tab_Daoham);
            app.NhpgiatricntinhaohamEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.EditField_x = uieditfield(app.Tab_Daoham, 'numeric');
            app.EditField_x.ValueChangedFcn = createCallbackFcn(app, @EditField_xValueChanged, true);
            app.KtquaEditFieldLabel = uilabel(app.Tab_Daoham);
            app.KtquaEditFieldLabel.Text = 'Kết quả';
            app.EditField_y = uieditfield(app.Tab_Daoham, 'numeric');
            app.EditField_y.Editable = 'off';
            app.HoacLabel = uilabel(app.Tab_Daoham);
            app.HoacLabel.Text = 'Hoặc';
            app.ChongiatrisaisDropDownLabel = uilabel(app.Tab_Daoham);
            app.ChongiatrisaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.DropDown_Saiso = uidropdown(app.Tab_Daoham);
            app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
            app.DropDown_Saiso.ValueChangedFcn = createCallbackFcn(app, @Button_CalculatePushed, true);
            app.DropDown_Saiso.Value = 'O(h)';
            app.NhpgiatriyLabel = uilabel(app.Tab_Daoham);
            app.NhpgiatriyLabel.Text = 'Nhập giá trị y';
            app.EditField_ya = uieditfield(app.Tab_Daoham, 'text');
            app.NhpgiatrixLabel = uilabel(app.Tab_Daoham);
            app.NhpgiatrixLabel.Text = 'Nhập giá trị x';
            app.EditField_xa = uieditfield(app.Tab_Daoham, 'text');
            app.PhantinhLabel = uilabel(app.Tab_Daoham);
            app.PhantinhLabel.Text = 'Phần tính';
            app.Button_Delete = uibutton(app.Tab_Daoham, 'push');
            app.Button_Delete.ButtonPushedFcn = createCallbackFcn(app, @Button_DeletePushed, true);
            app.Button_Delete.Text = 'Xoá kết quả';
            app.NhapmauLabel = uilabel(app.Tab_Daoham);
            app.NhapmauLabel.Text = 'Nhập mẫu';
            app.ChoncachnhpDropDownLabel = uilabel(app.Tab_Daoham);
            app.ChoncachnhpDropDownLabel.Text = 'Chọn cách nhập';
            app.DropDown_Way = uidropdown(app.Tab_Daoham);
            app.DropDown_Way.Items = {'Nhập mẫu', 'Nhập hàm số'};
            app.DropDown_Way.Value = 'Nhập mẫu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Daoham

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end