classdef Group_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab_Nghiem                      matlab.ui.container.Tab
        phuongphap                      matlab.ui.control.DropDown
        PhngphapDropDownLabel           matlab.ui.control.Label
        hamhoitu                        matlab.ui.control.EditField
        HmhitlpEditFieldLabel           matlab.ui.control.Label
        X                               matlab.ui.control.NumericEditField
        lntrcXEditFieldLabel            matlab.ui.control.Label
        ketqua                          matlab.ui.control.EditField
        KtquEditFieldLabel              matlab.ui.control.Label
        solanlap                        matlab.ui.control.EditField
        SlnlpEditFieldLabel             matlab.ui.control.Label
        saisochophep                    matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel        matlab.ui.control.Label
        khoangphanlya                   matlab.ui.control.NumericEditField
        KhongphnlynghimEditFieldLabel   matlab.ui.control.Label
        phuongtrinh                     matlab.ui.control.EditField
        PhngtrnhEditFieldLabel          matlab.ui.control.Label
        bd                              matlab.ui.control.NumericEditField
        khoangphanlyb                   matlab.ui.control.NumericEditField
        tinh                            matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Tab_Noisuy                      matlab.ui.container.Tab
        Panel_Setup                     matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        ChonPhngphapNiSuyDropDownLabel  matlab.ui.control.Label
        out_put                         matlab.ui.control.TextArea
        aThcNiSuyLabel                  matlab.ui.control.Label
        Gia_tri_x                       matlab.ui.control.EditField
        NhpdliuxLabel_2                 matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        Gia_tri_y                       matlab.ui.control.EditField
        NhpDLiuyLabel                   matlab.ui.control.Label
        Gia_tri_noisuy                  matlab.ui.control.NumericEditField
        GiTrCnNiSuyLabel                matlab.ui.control.Label
        Panel_Control                   matlab.ui.container.Panel
        RESETButton_1                   matlab.ui.control.Button
        RUNButton_1                     matlab.ui.control.Button
        Panel_Result                    matlab.ui.container.Panel
        ket_qua_noisuy                  matlab.ui.control.NumericEditField
        KtQuNiSuyEditFieldLabel         matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        Tab_Daoham                      matlab.ui.container.Tab
        DropDown_Way                    matlab.ui.control.DropDown
        ChoncachnhpDropDownLabel        matlab.ui.control.Label
        NhapmauLabel                    matlab.ui.control.Label
        Button_Delete                   matlab.ui.control.Button
        PhantinhLabel                   matlab.ui.control.Label
        EditField_xa                    matlab.ui.control.EditField
        NhpgiatrixLabel                 matlab.ui.control.Label
        EditField_ya                    matlab.ui.control.EditField
        NhpgiatriyLabel                 matlab.ui.control.Label
        DropDown_Saiso                  matlab.ui.control.DropDown
        ChongiatrisaisDropDownLabel     matlab.ui.control.Label
        HoacLabel                       matlab.ui.control.Label
        EditField_y                     matlab.ui.control.NumericEditField
        KtquaEditFieldLabel             matlab.ui.control.Label
        EditField_x                     matlab.ui.control.NumericEditField
        NhpgiatricntinhaohamEditFieldLabel  matlab.ui.control.Label
        DropDown_Choose                 matlab.ui.control.DropDown
        ChonphngphapaohamDropDownLabel  matlab.ui.control.Label
        EditField_h                     matlab.ui.control.NumericEditField
        NhpbcnhayhEditFieldLabel        matlab.ui.control.Label
        Button_Calculate                matlab.ui.control.Button
        EditField_fx                    matlab.ui.control.EditField
        HamsfxLabel                     matlab.ui.control.Label
        Tab_Hoiquy                      matlab.ui.container.Tab
        KtqudonEditField                matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel           matlab.ui.control.Label
        NhpdliuyEditField               matlab.ui.control.EditField
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        NhpdliuxEditField               matlab.ui.control.EditField
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
        NhpgitrcndonEditField           matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel      matlab.ui.control.Label
        KtquphngtrnhhiquyEditField      matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        ChnphngphphiquyDropDown         matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel    matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        UIAxes_1                        matlab.ui.control.UIAxes
        Tab_Tichphan                    matlab.ui.container.Tab
        KQEditField                     matlab.ui.control.NumericEditField
        KtquaEditFieldLabel_2           matlab.ui.control.Label
        DropDownChonin                  matlab.ui.control.DropDown
        ChncchnhpDropDownLabel          matlab.ui.control.Label
        EditFieldY                      matlab.ui.control.EditField
        NhpdliuyLabel                   matlab.ui.control.Label
        EditFieldX                      matlab.ui.control.EditField
        NhpdliuxLabel                   matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        DropDownChonpp                  matlab.ui.control.DropDown
        ChnphngphpLabel                 matlab.ui.control.Label
        EditFieldN                      matlab.ui.control.NumericEditField
        NhpNLabel                       matlab.ui.control.Label
        EditFieldFx                     matlab.ui.control.EditField
        NhphmsLabel                     matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        NhpcntnhtchphnLabel             matlab.ui.control.Label
        hoacLabel                       matlab.ui.control.Label
        Tab_GioithieuNhom               matlab.ui.container.Tab
        Label_Mission5                  matlab.ui.control.Label
        Label_Mission4                  matlab.ui.control.Label
        Label_Mission3                  matlab.ui.control.Label
        Label_Mission2                  matlab.ui.control.Label
        Label_Mission1                  matlab.ui.control.Label
        Label_ID5                       matlab.ui.control.Label
        Label_ID4                       matlab.ui.control.Label
        Label_ID3                       matlab.ui.control.Label
        Label_ID2                       matlab.ui.control.Label
        Label_ID1                       matlab.ui.control.Label
        Label_Member5                   matlab.ui.control.Label
        Label_Member4                   matlab.ui.control.Label
        Label_Member2                   matlab.ui.control.Label
        Label_Member3                   matlab.ui.control.Label
        Label_Member1                   matlab.ui.control.Label
        Label_Missons                   matlab.ui.control.Label
        Label_StudentID                 matlab.ui.control.Label
        Label_Members                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button_Calculate, DropDown_Saiso
        function Button_CalculatePushed(app, event)
            Way = app.DropDown_Way.Value;
            x = app.EditField_x.Value;
            Choose = app.DropDown_Choose.Value;
            Saiso = app.DropDown_Saiso.Value;
            if Way == "Nhập mẫu"
                xa = str2num(app.EditField_xa.Value);
                ya = str2num(app.EditField_ya.Value);
                y = Taylorapp(xa, ya, x, Choose, Saiso);
            else
                fx = app.EditField_fx.Value;
                h = app.EditField_h.Value;
                y = Tinhdaohamfx(fx, h, x, Choose, Saiso);
            end
            app.EditField_y.Value = y;
        end

        % Button pushed function: Button_Delete
        function Button_DeletePushed(app, event)
            app.EditField_xa.Value = '';
            app.EditField_ya.Value = '';
            app.EditField_fx.Value = '';
            app.EditField_h.Value = 0;
            app.DropDown_Choose.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DropDown_Choose.Value = 'Xấp xỉ tiến';
            app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
            app.DropDown_Saiso.Value = 'O(h)';
            app.EditField_x.Value = 0;
            app.EditField_y.Value = 0;
            app.DropDown_Way.Value = 'Nhập mẫu';
        end

        % Value changed function: DropDown_Choose, EditField_x
        function EditField_xValueChanged(app, event)
            x = app.EditField_x.Value;
            Way = app.DropDown_Way.Value;
            if Way == "Nhập mẫu"
                xa = str2num(app.EditField_xa.Value);
                if x == xa(1)
                    app.DropDown_Choose.Items = {'Xấp xỉ tiến'};
                elseif x == xa(end)
                    app.DropDown_Choose.Items = {'Xấp xỉ lùi'};
                else
                    app.DropDown_Choose.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
                end
                Choose = app.DropDown_Choose.Value;
                if Choose == "Xấp xỉ trung tâm"
                    if length(xa) == 3 || x == xa(2) || x == xa(length(xa) - 1)
                        app.DropDown_Saiso.Items = {'O(h^2)'};
                    else
                        app.DropDown_Saiso.Items = {'O(h^2)', 'O(h^4)'};
                    end
                elseif Choose == "Xấp xỉ tiến"
                    if x == xa(length(xa) - 1)
                        app.DropDown_Saiso.Items = {'O(h)'};
                    else
                        app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
                    end
                else
                    if x == xa(2)
                        app.DropDown_Saiso.Items = {'O(h)'};
                    else
                        app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
                    end
                end
            else
                Choose = app.DropDown_Choose.Value;
                if Choose == "Xấp xỉ trung tâm"
                    app.DropDown_Saiso.Items = {'O(h^2)', 'O(h^4)'};
                else
                    app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
                end
            end
        end

        % Callback function: X, phuongphap, tinh
        function tinhButtonPushed(app, event)
            sschophep = app.saisochophep.Value();
            a = app.khoangphanlya.Value();
            b = app.khoangphanlyb.Value();
            pp = app.phuongphap.Value();
            fx= app.phuongtrinh.Value();
            range=app.X.Value();
            start=app.bd.Value();
            if pp == "Chia đôi"
                [c,n] = ChiaDoi(fx,a,b,sschophep);
                app.solanlap.Value = sprintf('%s', num2str(n));
                app.ketqua.Value = sprintf('%s', num2str(c));
            elseif pp == "Tiếp tuyến"
                [c,n] = TiepTuyen(fx,a,b,sschophep);
                app.solanlap.Value = sprintf('%s', num2str(n));
                app.ketqua.Value = sprintf('%s', num2str(c));
            else
                fp= app.hamhoitu.Value();
                [c,n] = LapDon(fx,fp,a,b,sschophep);
                app.solanlap.Value = sprintf('%s', num2str(n));
                app.ketqua.Value = sprintf('%s', num2str(c));
            end
            %ve do thi
            x=start:0.001:range;
            fx= str2func(['@(x)',fx]);
            y=fx(x);
            plot(app.UIAxes,x,y);
            hold(app.UIAxes, "on");
            plot(app.UIAxes,c, fx(c), 'ro');
            hold(app.UIAxes, "off");
        end

        % Callback function: DropDownChonpp, RUNButton
        function RUNButtonPushed(app, event)
            x = app.EditFieldX.Value;
            y = app.EditFieldY.Value;
            x = str2num(x);
            y = str2num(y);
            fx = app.EditFieldFx.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            N = app.EditFieldN.Value;
            pp = app.DropDownChonpp.Value;
            choninput = app.DropDownChonin.Value;
            if choninput == 0
                tphan = TichPhanHinhThangxy(x,y);
            else
                tphan = Tinhtichphan(fx,a,b,N,pp);
            end
            app.KQEditField.Value = tphan;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            phuongphap = app.ChnphngphphiquyDropDown.Value;
    % Chuyển đổi          
x = app.NhpdliuxEditField.Value;
y = app.NhpdliuyEditField.Value;
z = app.NhpgitrcndonEditField.Value;
x = str2num(x);
y = str2num(y);
if strcmp(phuongphap, 'Tuyến tính')
    [a, b] = Hoiquytuyentinh(x, y);
    f = @(x) b + a * x;
    fplot(app.UIAxes_1, f, [min(x), max(x)], 'DisplayName', 'Đường thẳng hồi quy');
    hold(app.UIAxes_1, 'on');
    plot(app.UIAxes_1, x, y, 'o', 'DisplayName', 'các điểm đo được');
    hold(app.UIAxes_1, 'off');
    legend(app.UIAxes_1, 'Location', 'Best');
    title(app.UIAxes_1, 'Hồi quy tuyến tính');

    app.KtquphngtrnhhiquyEditField.Value = [num2str(a) ' *x  ' num2str(b)];
    app.KtqudonEditField.Value = a*z+ b;
elseif strcmp(phuongphap, 'Hàm mũ ')
    [a, b] = Hammu(x, y);
    f = @(x) a * x.^b;
    fplot(app.UIAxes_1, f, [min(x), max(x)], 'DisplayName', 'Đường thẳng hồi quy');
    hold(app.UIAxes_1, 'on');
    plot(app.UIAxes_1, x, y, 'o', 'DisplayName', 'các điểm đo được');
    hold(app.UIAxes_1, 'off');
    legend(app.UIAxes_1, 'Location', 'Best');
    title(app.UIAxes_1, 'Hồi quy hàm mũ Dạng y = ax^b');

    app.KtquphngtrnhhiquyEditField.Value = [num2str(a) ' x ^ ' num2str(b)];
    app.KtqudonEditField.Value = a*z .^b;
else
    [a, b] = logarit(x, y);
    f = @(x) a * log(x) + b;
    fplot(app.UIAxes_1, f, [min(x), max(x)], 'DisplayName', 'Đường thẳng hồi quy');
    hold(app.UIAxes_1, 'on');
    plot(app.UIAxes_1, x, y, 'o', 'DisplayName', 'các điểm đo được');
    hold(app.UIAxes_1, 'off');
    legend(app.UIAxes_1, 'Location', 'Best');
    title(app.UIAxes_1, 'Hồi quy logarit');

    app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'ln(x) + ' num2str(b)];
    app.KtqudonEditField.Value = a*log(z) + b;
    
end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.NhpdliuxEditField.Value = '';
            app.NhpdliuyEditField.Value = '';
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính';
            app.NhpgitrcndonEditField.Value = 0;
            app.KtquphngtrnhhiquyEditField.Value = '';
            app.KtqudonEditField.Value = 0;
            cla(app.UIAxes_1);
        end

        % Callback function: RUNButton_1, Slider
        function RUNButton_1Pushed(app, event)
x = str2num(app.Gia_tri_x.Value);
y = str2num(app.Gia_tri_y.Value);
list = app.DropDown.Value;
t = app.Gia_tri_noisuy.Value;

if list == 0
    result = noisuy_newton(x, y);
    p = polyval(result, t);
    chuoi = 'Kết quả: ';
    for i = length(result):-1:1
        if result(i) ~= 0
            % Hiển thị hệ số và biến x
            if i == length(result)
                chuoi = [chuoi, sprintf('%+g', result(i))];
            else
                if length(result)-i == 1
                    chuoi = [chuoi, sprintf('%+gx', result(i))];
                else
                    chuoi = [chuoi, sprintf('%+gx^%d ', result(i), length(result)-i)];
                end
            end
        end
    end

    resultString = sprintf('%s= %0.2f', chuoi, p);
    app.out_put.Value = resultString;
    app.ket_qua_noisuy.Value = p;

else
    a = noisuy_lagrange(x, y);
    p = polyval(a, t);
    chuoi = 'Kết quả: ';
    for i = length(a):-1:1
        if a(i) ~= 0
            % Hiển thị hệ số và biến x
            if i == length(a)
                chuoi = [chuoi, sprintf('%+g', a(i))];
            else
                if length(a)-i == 1
                    chuoi = [chuoi, sprintf('%+gx', a(i))];
                else
                    chuoi = [chuoi, sprintf('%+gx^%d ', a(i), length(a)-i)];
                end
            end
        end
    end

    resultString = sprintf('%s= %0.2f', chuoi, p);
    app.out_put.Value = resultString;
    app.ket_qua_noisuy.Value = p;
    app.ket_qua_noisuy.Value = p;
end

%--------------------------------------------------------------------------------

if list == "Nội Suy Newton"
    [x_values, y_values, t, p] = app_dothi_newton(x, y, t);
    plot(app.UIAxes_2, x_values, y_values, 'r-', LineWidth = 2);
    hold(app.UIAxes_2, 'on');
    plot(app.UIAxes_2, x, y, 'bo', 'MarkerSize', 8, 'MarkerFaceColor', 'b');
    plot(app.UIAxes_2, t, p, 'go', 'MarkerSize', 8, 'MarkerFaceColor', 'g');

    title(app.UIAxes_2, 'ĐỒ THỊ NỘI SUY NEWTON');
    xlabel(app.UIAxes_2, 'x');
    ylabel(app.UIAxes_2, 'f(x)');
    legend(app.UIAxes_2, 'f(x) Newton', 'Data Points', ['Điểm nội suy x = ' num2str(t)]);
    grid(app.UIAxes_2, 'on');
    hold(app.UIAxes_2, 'off');
    
else
    [a_values, b_values, t1, p1] = app_dothi_lagrange(x, y, t);
    plot(app.UIAxes_2, a_values, b_values, 'y-', LineWidth = 2);
    hold(app.UIAxes_2, 'on');
    plot(app.UIAxes_2, x, y, 'ko', 'MarkerSize', 8, 'MarkerFaceColor', 'k');
    plot(app.UIAxes_2, t1, p1, 'mo', 'MarkerSize', 8, 'MarkerFaceColor', 'm');

    title(app.UIAxes_2, 'ĐỒ THỊ NỘI SUY LAGRANGE');
    xlabel(app.UIAxes_2, 'x');
    ylabel(app.UIAxes_2, 'f(x)');
    legend(app.UIAxes_2, 'f(x) Lagrange', 'Điểm dữ liệu', ['Điểm nội suy x = ' num2str(t)]);
    grid(app.UIAxes_2, 'on');
    hold(app.UIAxes_2, 'off');
end
            newX = str2num(app.Gia_tri_x.Value); 
            app.Slider.Limits = [min(newX), max(newX)];
           
            value_slider = app.Slider.Value;
            app.Gia_tri_noisuy.Value = value_slider;
        end

        % Button pushed function: RESETButton_1
        function RESETButton_1Pushed(app, event)
            app.Gia_tri_x.Value = '';
            app.Gia_tri_y.Value = '';
            app.DropDown.Value = 'Nội Suy Newton';
            app.out_put.Value = '';
            app.Gia_tri_noisuy.Value = 0;
            app.ket_qua_noisuy.Value = 0;
            cla(app.UIAxes_2);
            app.Slider.Limits = [0, 100];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Nghiem = uitab(app.TabGroup);
            app.Tab_Nghiem.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.Tab_Nghiem);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.tinh = uibutton(app.Tab_Nghiem, 'push');
            app.tinh.ButtonPushedFcn = createCallbackFcn(app, @tinhButtonPushed, true);
            app.tinh.Text = 'Tính';
            app.khoangphanlyb = uieditfield(app.Tab_Nghiem, 'numeric');
            app.bd = uieditfield(app.Tab_Nghiem, 'numeric');
            app.PhngtrnhEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.PhngtrnhEditFieldLabel.Text = 'Phương trình';
            app.phuongtrinh = uieditfield(app.Tab_Nghiem, 'text');
            app.KhongphnlynghimEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.KhongphnlynghimEditFieldLabel.Text = 'Khoảng phân ly nghiệm';
            app.khoangphanlya = uieditfield(app.Tab_Nghiem, 'numeric');
            app.SaischophpEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.saisochophep = uieditfield(app.Tab_Nghiem, 'numeric');
            app.SlnlpEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.solanlap = uieditfield(app.Tab_Nghiem, 'text');
            app.solanlap.Editable = 'off';
            app.KtquEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.ketqua = uieditfield(app.Tab_Nghiem, 'text');
            app.ketqua.Editable = 'off';
            app.lntrcXEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.lntrcXEditFieldLabel.Text = 'Độ lớn trục X';
            app.X = uieditfield(app.Tab_Nghiem, 'numeric');
            app.X.ValueChangedFcn = createCallbackFcn(app, @tinhButtonPushed, true);
            app.HmhitlpEditFieldLabel = uilabel(app.Tab_Nghiem);
            app.HmhitlpEditFieldLabel.Text = 'Hàm hội tụ(lặp)';
            app.hamhoitu = uieditfield(app.Tab_Nghiem, 'text');
            app.PhngphapDropDownLabel = uilabel(app.Tab_Nghiem);
            app.PhngphapDropDownLabel.Text = 'Phương pháp';
            app.phuongphap = uidropdown(app.Tab_Nghiem);
            app.phuongphap.Items = {'Lặp', 'Tiếp tuyến', 'Chia đôi'};
            app.phuongphap.ValueChangedFcn = createCallbackFcn(app, @tinhButtonPushed, true);
            app.phuongphap.Value = 'Lặp';
            app.Tab_Noisuy = uitab(app.TabGroup);
            app.Tab_Noisuy.Title = 'Nội suy';
            app.Panel_Result = uipanel(app.Tab_Noisuy);
            app.Panel_Result.Title = 'BẢNG KẾT QUẢ';
            app.UIAxes_2 = uiaxes(app.Panel_Result);
            title(app.UIAxes_2, 'ĐỒ THỊ NỘI SUY')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'f(x)')
            zlabel(app.UIAxes_2, 'Z')
            app.KtQuNiSuyEditFieldLabel = uilabel(app.Panel_Result);
            app.KtQuNiSuyEditFieldLabel.Text = 'Kết Quả Nội Suy';
            app.ket_qua_noisuy = uieditfield(app.Panel_Result, 'numeric');
            app.ket_qua_noisuy.Editable = 'off';
            app.Panel_Control = uipanel(app.Tab_Noisuy);
            app.Panel_Control.Title = 'NÚT LỆNH ĐIỀU KHIỂN';
            app.RUNButton_1 = uibutton(app.Panel_Control, 'push');
            app.RUNButton_1.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_1Pushed, true);
            app.RUNButton_1.Text = 'RUN';
            app.RESETButton_1 = uibutton(app.Panel_Control, 'push');
            app.RESETButton_1.ButtonPushedFcn = createCallbackFcn(app, @RESETButton_1Pushed, true);
            app.RESETButton_1.Text = 'RESET';
            app.Panel_Setup = uipanel(app.Tab_Noisuy);
            app.Panel_Setup.Title = 'BẢNG THIẾT LẬP';
            app.GiTrCnNiSuyLabel = uilabel(app.Panel_Setup);
            app.GiTrCnNiSuyLabel.Text = {'Giá Trị '; 'Cần Nội Suy'};
            app.Gia_tri_noisuy = uieditfield(app.Panel_Setup, 'numeric');
            app.Gia_tri_noisuy.Editable = 'off';
            app.NhpDLiuyLabel = uilabel(app.Panel_Setup);
            app.NhpDLiuyLabel.Text = {'Nhập '; 'Dữ Liệu y'};
            app.Gia_tri_y = uieditfield(app.Panel_Setup, 'text');
            app.SliderLabel = uilabel(app.Panel_Setup);
            app.SliderLabel.Text = '';
            app.Slider = uislider(app.Panel_Setup);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @RUNButton_1Pushed, true);
            app.NhpdliuxLabel_2 = uilabel(app.Panel_Setup);
            app.NhpdliuxLabel_2.Text = {'Nhập'; 'Dữ Liệu x'};
            app.Gia_tri_x = uieditfield(app.Panel_Setup, 'text');
            app.aThcNiSuyLabel = uilabel(app.Panel_Setup);
            app.aThcNiSuyLabel.Text = {'Đa Thức'; 'Nội Suy'};
            app.out_put = uitextarea(app.Panel_Setup);
            app.out_put.Editable = 'off';
            app.ChonPhngphapNiSuyDropDownLabel = uilabel(app.Panel_Setup);
            app.ChonPhngphapNiSuyDropDownLabel.Text = {'Chọn'; 'Phương pháp'; 'Nội Suy'};
            app.DropDown = uidropdown(app.Panel_Setup);
            app.DropDown.Items = {'Nội Suy Newton', 'Nội Suy Lagrange'};
            app.DropDown.Value = 'Nội Suy Newton';
            app.Tab_Daoham = uitab(app.TabGroup);
            app.Tab_Daoham.Title = 'Đạo hàm';
            app.HamsfxLabel = uilabel(app.Tab_Daoham);
            app.HamsfxLabel.Text = 'Nhập hàm số f(x)';
            app.EditField_fx = uieditfield(app.Tab_Daoham, 'text');
            app.Button_Calculate = uibutton(app.Tab_Daoham, 'push');
            app.Button_Calculate.ButtonPushedFcn = createCallbackFcn(app, @Button_CalculatePushed, true);
            app.Button_Calculate.Text = 'Tính đạo hàm';
            app.NhpbcnhayhEditFieldLabel = uilabel(app.Tab_Daoham);
            app.NhpbcnhayhEditFieldLabel.Text = 'Nhập bước nhảy (h)';
            app.EditField_h = uieditfield(app.Tab_Daoham, 'numeric');
            app.ChonphngphapaohamDropDownLabel = uilabel(app.Tab_Daoham);
            app.ChonphngphapaohamDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.DropDown_Choose = uidropdown(app.Tab_Daoham);
            app.DropDown_Choose.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DropDown_Choose.ValueChangedFcn = createCallbackFcn(app, @EditField_xValueChanged, true);
            app.DropDown_Choose.Value = 'Xấp xỉ tiến';
            app.NhpgiatricntinhaohamEditFieldLabel = uilabel(app.Tab_Daoham);
            app.NhpgiatricntinhaohamEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.EditField_x = uieditfield(app.Tab_Daoham, 'numeric');
            app.EditField_x.ValueChangedFcn = createCallbackFcn(app, @EditField_xValueChanged, true);
            app.KtquaEditFieldLabel = uilabel(app.Tab_Daoham);
            app.KtquaEditFieldLabel.Text = 'Kết quả';
            app.EditField_y = uieditfield(app.Tab_Daoham, 'numeric');
            app.EditField_y.Editable = 'off';
            app.HoacLabel = uilabel(app.Tab_Daoham);
            app.HoacLabel.Text = 'Hoặc';
            app.ChongiatrisaisDropDownLabel = uilabel(app.Tab_Daoham);
            app.ChongiatrisaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.DropDown_Saiso = uidropdown(app.Tab_Daoham);
            app.DropDown_Saiso.Items = {'O(h)', 'O(h^2)'};
            app.DropDown_Saiso.ValueChangedFcn = createCallbackFcn(app, @Button_CalculatePushed, true);
            app.DropDown_Saiso.Value = 'O(h)';
            app.NhpgiatriyLabel = uilabel(app.Tab_Daoham);
            app.NhpgiatriyLabel.Text = 'Nhập giá trị y';
            app.EditField_ya = uieditfield(app.Tab_Daoham, 'text');
            app.NhpgiatrixLabel = uilabel(app.Tab_Daoham);
            app.NhpgiatrixLabel.Text = 'Nhập giá trị x';
            app.EditField_xa = uieditfield(app.Tab_Daoham, 'text');
            app.PhantinhLabel = uilabel(app.Tab_Daoham);
            app.PhantinhLabel.Text = 'Phần tính';
            app.Button_Delete = uibutton(app.Tab_Daoham, 'push');
            app.Button_Delete.ButtonPushedFcn = createCallbackFcn(app, @Button_DeletePushed, true);
            app.Button_Delete.Text = 'Xoá kết quả';
            app.NhapmauLabel = uilabel(app.Tab_Daoham);
            app.NhapmauLabel.Text = 'Nhập mẫu';
            app.ChoncachnhpDropDownLabel = uilabel(app.Tab_Daoham);
            app.ChoncachnhpDropDownLabel.Text = 'Chọn cách nhập';
            app.DropDown_Way = uidropdown(app.Tab_Daoham);
            app.DropDown_Way.Items = {'Nhập mẫu', 'Nhập hàm số'};
            app.DropDown_Way.Value = 'Nhập mẫu';
            app.Tab_Hoiquy = uitab(app.TabGroup);
            app.Tab_Hoiquy.Title = 'Hồi quy';
            app.UIAxes_1 = uiaxes(app.Tab_Hoiquy);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            zlabel(app.UIAxes_1, 'Z')
            app.RunButton = uibutton(app.Tab_Hoiquy, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'RUN';
            app.ResetButton = uibutton(app.Tab_Hoiquy, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ChnphngphphiquyDropDownLabel = uilabel(app.Tab_Hoiquy);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChnphngphphiquyDropDown = uidropdown(app.Tab_Hoiquy);
            app.ChnphngphphiquyDropDown.Items = {'Tuyến tính', 'Hàm mũ ', 'Logarit'};
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính';
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.Tab_Hoiquy);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.Tab_Hoiquy, 'text');
            app.KtquphngtrnhhiquyEditField.Editable = 'off';
            app.NhpgitrcndonEditFieldLabel = uilabel(app.Tab_Hoiquy);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.Tab_Hoiquy, 'numeric');
            app.NhpdliuxEditFieldLabel = uilabel(app.Tab_Hoiquy);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.Tab_Hoiquy, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.Tab_Hoiquy);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.Tab_Hoiquy, 'text');
            app.KtqudonEditFieldLabel = uilabel(app.Tab_Hoiquy);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.Tab_Hoiquy, 'numeric');
            app.KtqudonEditField.Editable = 'off';
            app.Tab_Tichphan = uitab(app.TabGroup);
            app.Tab_Tichphan.Title = 'Tích phân';
            app.hoacLabel = uilabel(app.Tab_Tichphan);
            app.hoacLabel.Text = 'hoặc';
            app.NhpcntnhtchphnLabel = uilabel(app.Tab_Tichphan);
            app.NhpcntnhtchphnLabel.Text = 'Nhập cận để tính tích phân';
            app.RUNButton = uibutton(app.Tab_Tichphan, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'Run';
            app.NhphmsLabel = uilabel(app.Tab_Tichphan);
            app.NhphmsLabel.Text = 'Nhập hàm số';
            app.EditFieldFx = uieditfield(app.Tab_Tichphan, 'text');
            app.NhpNLabel = uilabel(app.Tab_Tichphan);
            app.NhpNLabel.Text = 'Nhập N';
            app.EditFieldN = uieditfield(app.Tab_Tichphan, 'numeric');
            app.ChnphngphpLabel = uilabel(app.Tab_Tichphan);
            app.ChnphngphpLabel.Text = 'Chọn phương pháp tích phân';
            app.DropDownChonpp = uidropdown(app.Tab_Tichphan);
            app.DropDownChonpp.Items = {'Hình thang', 'Simpson1/3', 'Simpson3/8'};
            app.DropDownChonpp.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.DropDownChonpp.Value = 'Hình thang';
            app.aEditFieldLabel = uilabel(app.Tab_Tichphan);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.Tab_Tichphan, 'numeric');
            app.bEditFieldLabel = uilabel(app.Tab_Tichphan);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.Tab_Tichphan, 'numeric');
            app.NhpdliuxLabel = uilabel(app.Tab_Tichphan);
            app.NhpdliuxLabel.Text = 'Nhập dữ liệu x';
            app.EditFieldX = uieditfield(app.Tab_Tichphan, 'text');
            app.NhpdliuyLabel = uilabel(app.Tab_Tichphan);
            app.NhpdliuyLabel.Text = 'Nhập dữ liệu y';
            app.EditFieldY = uieditfield(app.Tab_Tichphan, 'text');
            app.ChncchnhpDropDownLabel = uilabel(app.Tab_Tichphan);
            app.ChncchnhpDropDownLabel.Text = 'Chọn cách nhập ';
            app.DropDownChonin = uidropdown(app.Tab_Tichphan);
            app.DropDownChonin.Items = {'Nhập dữ liệu', 'Nhập hàm số'};
            app.DropDownChonin.ItemsData = [0 1];
            app.DropDownChonin.Value = 1;
            app.KtquaEditFieldLabel_2 = uilabel(app.Tab_Tichphan);
            app.KtquaEditFieldLabel_2.Text = 'Kết quả';
            app.KQEditField = uieditfield(app.Tab_Tichphan, 'numeric');
            app.KQEditField.Editable = 'off';
            app.Tab_GioithieuNhom = uitab(app.TabGroup);
            app.Tab_GioithieuNhom.Title = 'Giới thiệu Nhóm';
            app.Label_Members = uilabel(app.Tab_GioithieuNhom);
            app.Label_Members.Text = 'Thành viên nhóm';
            app.Label_StudentID = uilabel(app.Tab_GioithieuNhom);
            app.Label_StudentID.Text = 'MSSV';
            app.Label_Missons = uilabel(app.Tab_GioithieuNhom);
            app.Label_Missons.Text = 'Nhiệm vụ';
            app.Label_Member1 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Member1.Text = 'Nguyễn Nhật Thái';
            app.Label_Member3 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Member3.Text = 'Trần Xuân Đức';
            app.Label_Member2 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Member2.Text = 'Nguyễn Văn Điều';
            app.Label_Member4 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Member4.Text = 'Tô Hoàng Duy';
            app.Label_Member5 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Member5.Text = 'Trương Đức Duy';
            app.Label_ID1 = uilabel(app.Tab_GioithieuNhom);
            app.Label_ID1.Text = '21207098';
            app.Label_ID2 = uilabel(app.Tab_GioithieuNhom);
            app.Label_ID2.Text = '21207138';
            app.Label_ID3 = uilabel(app.Tab_GioithieuNhom);
            app.Label_ID3.Text = '21207142';
            app.Label_ID4 = uilabel(app.Tab_GioithieuNhom);
            app.Label_ID4.Text = '21207147';
            app.Label_ID5 = uilabel(app.Tab_GioithieuNhom);
            app.Label_ID5.Text = '21207148';
            app.Label_Mission1 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Mission1.Text = 'Làm phần nghiệm';
            app.Label_Mission2 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Mission2.Text = 'Làm phần nội suy';
            app.Label_Mission3 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Mission3.Text = 'Làm phần tích phân';
            app.Label_Mission4 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Mission4.Text = 'Làm phần đạo hàm';
            app.Label_Mission5 = uilabel(app.Tab_GioithieuNhom);
            app.Label_Mission5.Text = 'Làm phần hồi quy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Group_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end