classdef hoiquy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ResetButton                   matlab.ui.control.Button
        KtqudonEditField              matlab.ui.control.NumericEditField
        KtqudonEditFieldLabel         matlab.ui.control.Label
        NhpdliuyEditField             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        NhpdliuxEditField             matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        RUNButton                     matlab.ui.control.Button
        NhpgitrcndonEditField         matlab.ui.control.NumericEditField
        NhpgitrcndonEditFieldLabel    matlab.ui.control.Label
        KtquphngtrnhhiquyEditField    matlab.ui.control.EditField
        KtquphngtrnhhiquyEditFieldLabel  matlab.ui.control.Label
        ChnphngphphiquyDropDown       matlab.ui.control.DropDown
        ChnphngphphiquyDropDownLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            phuongphap = app.ChnphngphphiquyDropDown.Value;
           function [a1, a0] = Hoiquytuyentinh(x, y)
    n = length(x);
    sumx = 0;
    sumy = 0;
    sumxy = 0;
    sumx2 = 0;
    st = 0;
    sr = 0;

    for i = 1:n
        sumx = sumx + x(i);
        sumy = sumy + y(i);
        sumxy = sumxy + x(i) * y(i);
        sumx2 = sumx2 + x(i)^2;
    end

    xm = sumx / n;
    ym = sumy / n;

    a1 = (n * sumxy - sumx * sumy) / (n * sumx2 - sumx^2);
    a0 = ym - a1 * xm;

    for i = 1:n
        st = st + (y(i) - ym)^2;
        sr = sr + (y(i) - a1 * x(i) - a0)^2;
    end

end


            function [a, b] = Hammu(x, y)
    n = length(x);
    XX = log10(x);
    YY = log10(y);
    sumx = 0;
    sumy = 0;
    sumxy = 0;
    sumx2 = 0;
    st = 0;
    sr = 0;
    
    for i = 1:n
        sumx = sumx + XX(i);
        sumy = sumy + YY(i);
        sumxy = sumxy + XX(i) * YY(i);
        sumx2 = sumx2 + XX(i)^2;
    end

    xm = sumx / n;
    ym = sumy / n;

    A = (n * sumxy - sumx * sumy) / (n * sumx2 - sumx^2);
    B = ym - A * xm;

    for i = 1:n
        st = st + (YY(i) - ym)^2;
        sr = sr + (YY(i) - A * XX(i) - B)^2;
    end
  
    b = A;
    a = 10^B;
end


              function [a, b] = logarit(x,y)
n = length(x);
XX = log(x);
YY = y;
sumx = 0;
sumy = 0;
sumxy = 0;
sumx2 = 0;
st = 0;
sr = 0;
for i = 1:1:n
sumx = sumx + XX(i);
sumy = sumy + YY(i);
sumxy = sumxy + XX(i)*YY(i);
sumx2 = sumx2 + XX(i)*XX(i);
end
xm = sumx/n;
ym = sumy/n;
A = (n*sumxy - sumx*sumy)/(n*sumx2 - sumx*sumx);
B = ym - A*xm;
for i = 1:1: n
st = st + (YY(i) - ym)^2;
sr = sr + (YY(i) - A*XX(i) - B)^2;
end 
a = A;
b = B;
              end
    % Chuyển đổi          
x = app.NhpdliuxEditField.Value;
y = app.NhpdliuyEditField.Value;
z = app.NhpgitrcndonEditField.Value;
x = str2num(x);
y = str2num(y);


if strcmp(phuongphap, 'Tuyến tính')
    [a, b] = Hoiquytuyentinh(x, y);
    f = @(x) b + a * x;
    fplot(app.UIAxes, f, [min(x), max(x)], 'DisplayName', 'Đường thẳng hồi quy');
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, x, y, 'o', 'DisplayName', 'các điểm đo được');
    hold(app.UIAxes, 'off');
    legend(app.UIAxes, 'Location', 'Best');
    title(app.UIAxes, 'Hồi quy tuyến tính');

    app.KtquphngtrnhhiquyEditField.Value = [num2str(a) ' *x  ' num2str(b)];
    app.KtqudonEditField.Value = a*z+ b;


elseif strcmp(phuongphap, 'Hàm mũ ')
    [a, b] = Hammu(x, y);
    f = @(x) a * x.^b;
    fplot(app.UIAxes, f, [min(x), max(x)], 'DisplayName', 'Đường thẳng hồi quy');
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, x, y, 'o', 'DisplayName', 'các điểm đo được');
    hold(app.UIAxes, 'off');
    legend(app.UIAxes, 'Location', 'Best');
    title(app.UIAxes, 'Hồi quy hàm mũ Dạng y = ax^b');

    app.KtquphngtrnhhiquyEditField.Value = [num2str(a) ' x ^ ' num2str(b)];
    app.KtqudonEditField.Value = a*z .^b;


else
    [a, b] = logarit(x, y);
    f = @(x) a * log(x) + b;
    fplot(app.UIAxes, f, [min(x), max(x)], 'DisplayName', 'Đường thẳng hồi quy');
    hold(app.UIAxes, 'on');
    plot(app.UIAxes, x, y, 'o', 'DisplayName', 'các điểm đo được');
    hold(app.UIAxes, 'off');
    legend(app.UIAxes, 'Location', 'Best');
    title(app.UIAxes, 'Hồi quy logarit');

    app.KtquphngtrnhhiquyEditField.Value = [num2str(a) 'ln(x) + ' num2str(b)];
    app.KtqudonEditField.Value = a*log(z) + b;
    
end

       
        end

        % Callback function
        function NhpgitrxEditFieldValueChanged(app, event)
            
        end

        % Drop down opening function: ChnphngphphiquyDropDown
        function ChnphngphphiquyDropDownValueChanged(app, event)
            
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.NhpdliuxEditField.Value = '';
            app.NhpdliuyEditField.Value = '';
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính ';
            app.NhpgitrcndonEditField.Value = 0;
            app.KtquphngtrnhhiquyEditField.Value = '';
            app.KtqudonEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChnphngphphiquyDropDownLabel = uilabel(app.UIFigure);
            app.ChnphngphphiquyDropDownLabel.Text = 'Chọn phương pháp hồi quy';
            app.ChnphngphphiquyDropDown = uidropdown(app.UIFigure);
            app.ChnphngphphiquyDropDown.Items = {'Tuyến tính', 'Hàm mũ ', 'Logarit'};
            app.ChnphngphphiquyDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChnphngphphiquyDropDownValueChanged, true);
            app.ChnphngphphiquyDropDown.Value = 'Tuyến tính';
            app.KtquphngtrnhhiquyEditFieldLabel = uilabel(app.UIFigure);
            app.KtquphngtrnhhiquyEditFieldLabel.Text = 'Kết quả phương trình hồi quy';
            app.KtquphngtrnhhiquyEditField = uieditfield(app.UIFigure, 'text');
            app.NhpgitrcndonEditFieldLabel = uilabel(app.UIFigure);
            app.NhpgitrcndonEditFieldLabel.Text = 'Nhập giá trị cần dự đoán';
            app.NhpgitrcndonEditField = uieditfield(app.UIFigure, 'numeric');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.NhpdliuxEditFieldLabel = uilabel(app.UIFigure);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.NhpdliuxEditField = uieditfield(app.UIFigure, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.UIFigure);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.NhpdliuyEditField = uieditfield(app.UIFigure, 'text');
            app.KtqudonEditFieldLabel = uilabel(app.UIFigure);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.KtqudonEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hoiquy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end