classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        NghimTab                       matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        hamhoitu                       matlab.ui.control.EditField
        HmhitlpEditFieldLabel          matlab.ui.control.Label
        X                              matlab.ui.control.NumericEditField
        lntrcXEditFieldLabel           matlab.ui.control.Label
        ketqua                         matlab.ui.control.EditField
        KtquEditFieldLabel             matlab.ui.control.Label
        solanlap                       matlab.ui.control.EditField
        SlnlpEditFieldLabel_2          matlab.ui.control.Label
        listpp                         matlab.ui.control.ListBox
        PhngphpListBoxLabel            matlab.ui.control.Label
        saisochophep                   matlab.ui.control.NumericEditField
        SaischophpEditFieldLabel       matlab.ui.control.Label
        khoangphanlya                  matlab.ui.control.NumericEditField
        KhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        phuongtrinh                    matlab.ui.control.EditField
        PhngtrnhEditFieldLabel         matlab.ui.control.Label
        bd                             matlab.ui.control.NumericEditField
        khoangphanlyb                  matlab.ui.control.NumericEditField
        tinh                           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        NisuyTab                       matlab.ui.container.Tab
        KtquathcnisuyEditField         matlab.ui.control.EditField
        KtquathcnisuyEditFieldLabel    matlab.ui.control.Label
        KtqunisuyEditField             matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel        matlab.ui.control.Label
        GitrcnnisuyEditField           matlab.ui.control.NumericEditField
        GitrcnnisuyEditFieldLabel      matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        LagrangeButton                 matlab.ui.control.Button
        NewtonButton                   matlab.ui.control.Button
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: listpp
        function listppValueChanged(app, event)
            value = app.listpp.Value;
            
        end

        % Button pushed function: tinh
        function tinhButtonPushed(app, event)
            sschophep = app.saisochophep.Value();
            a = app.khoangphanlya.Value();
            b = app.khoangphanlyb.Value();
            pp=app.listpp.Value();
            fx= app.phuongtrinh.Value();
            range=app.X.Value();
            start=app.bd.Value();
            if strcmp(pp, 'chia đôi') % sosanh chuoi
                [c,n] = ChiaDoi(fx,a,b,sschophep);
                app.solanlap.Value = sprintf('%s', num2str(n));
                app.ketqua.Value = sprintf('%s', num2str(c));
            elseif strcmp(pp, 'tiếp tuyến')% ham so sanh chuoi
                [c,n] = TiepTuyen(fx,a,b,sschophep);
                app.solanlap.Value = sprintf('%s', num2str(n));
                app.ketqua.Value = sprintf('%s', num2str(c));
            elseif strcmp(pp, 'lặp')
                fp= app.hamhoitu.Value();
                [c,n] = LapDon(fx,fp,a,b,sschophep);
                app.solanlap.Value = sprintf('%s', num2str(n));
                app.ketqua.Value = sprintf('%s', num2str(c));

            end
            %ve do thi
            x=start:0.001:range;
            fx= str2func(['@(x)',fx]);
            y=fx(x);
            plot(app.UIAxes,x,y );
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.GridLayout = uigridlayout(app.NghimTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.tinh = uibutton(app.GridLayout, 'push');
            app.tinh.ButtonPushedFcn = createCallbackFcn(app, @tinhButtonPushed, true);
            app.tinh.Text = 'Tính';
            app.khoangphanlyb = uieditfield(app.GridLayout, 'numeric');
            app.bd = uieditfield(app.GridLayout, 'numeric');
            app.PhngtrnhEditFieldLabel = uilabel(app.GridLayout);
            app.PhngtrnhEditFieldLabel.Text = 'Phương trình';
            app.phuongtrinh = uieditfield(app.GridLayout, 'text');
            app.KhongphnlynghimEditFieldLabel = uilabel(app.GridLayout);
            app.KhongphnlynghimEditFieldLabel.Text = 'Khoảng phân ly nghiệm';
            app.khoangphanlya = uieditfield(app.GridLayout, 'numeric');
            app.SaischophpEditFieldLabel = uilabel(app.GridLayout);
            app.SaischophpEditFieldLabel.Text = 'Sai số cho phép';
            app.saisochophep = uieditfield(app.GridLayout, 'numeric');
            app.PhngphpListBoxLabel = uilabel(app.GridLayout);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.listpp = uilistbox(app.GridLayout);
            app.listpp.Items = {'lặp', 'tiếp tuyến', 'chia đôi'};
            app.listpp.ValueChangedFcn = createCallbackFcn(app, @listppValueChanged, true);
            app.listpp.Value = 'tiếp tuyến';
            app.SlnlpEditFieldLabel_2 = uilabel(app.GridLayout);
            app.SlnlpEditFieldLabel_2.Text = 'Số lần lặp';
            app.solanlap = uieditfield(app.GridLayout, 'text');
            app.KtquEditFieldLabel = uilabel(app.GridLayout);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.ketqua = uieditfield(app.GridLayout, 'text');
            app.lntrcXEditFieldLabel = uilabel(app.GridLayout);
            app.lntrcXEditFieldLabel.Text = 'Độ lớn trục X';
            app.X = uieditfield(app.GridLayout, 'numeric');
            app.HmhitlpEditFieldLabel = uilabel(app.GridLayout);
            app.HmhitlpEditFieldLabel.Text = 'Hàm hội tụ(lặp)';
            app.hamhoitu = uieditfield(app.GridLayout, 'text');
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes2 = uiaxes(app.NisuyTab);
            title(app.UIAxes2, 'Đồ thị ')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.xEditFieldLabel = uilabel(app.NisuyTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.NisuyTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.NisuyTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.NisuyTab, 'numeric');
            app.ButtonGroup = uibuttongroup(app.NisuyTab);
            app.ButtonGroup.Title = 'Button Group';
            app.NewtonButton = uibutton(app.ButtonGroup, 'push');
            app.NewtonButton.Text = 'Newton';
            app.LagrangeButton = uibutton(app.ButtonGroup, 'push');
            app.LagrangeButton.Text = 'Lagrange';
            app.GitrcnnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.GitrcnnisuyEditFieldLabel.Text = 'Giá trị cần nội suy';
            app.GitrcnnisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.KtqunisuyEditField = uieditfield(app.NisuyTab, 'numeric');
            app.KtquathcnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyEditFieldLabel.Text = 'Kết quả đa thức nội suy';
            app.KtquathcnisuyEditField = uieditfield(app.NisuyTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end